classdef mat2tec < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UITable                 matlab.ui.control.Table
        AddVarButton            matlab.ui.control.Button
        TitleEditFieldLabel     matlab.ui.control.Label
        TitleEditField          matlab.ui.control.EditField
        ZoneNameLabel           matlab.ui.control.Label
        ZoneNameEditField       matlab.ui.control.EditField
        BeginEditFieldLabel     matlab.ui.control.Label
        BeginEditField_1        matlab.ui.control.NumericEditField
        SkipEditFieldLabel      matlab.ui.control.Label
        SkipEditField_1         matlab.ui.control.NumericEditField
        EndEditFieldLabel       matlab.ui.control.Label
        EndEditField_1          matlab.ui.control.NumericEditField
        BrowseButton            matlab.ui.control.Button
        FilePathEditFieldLabel  matlab.ui.control.Label
        FilePathEditField       matlab.ui.control.EditField
        ExportButton            matlab.ui.control.Button
        RefreshButton           matlab.ui.control.Button
        BeginEditField_2        matlab.ui.control.NumericEditField
        BeginEditField_3        matlab.ui.control.NumericEditField
        SkipEditField_2         matlab.ui.control.NumericEditField
        SkipEditField_3         matlab.ui.control.NumericEditField
        EndEditField_2          matlab.ui.control.NumericEditField
        EndEditField_3          matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        vars
    end
    
    methods (Access = private)
        function refresh_data(app)
            app.vars = evalin('base','whos');
            if ~isempty(app.vars)
                temp={};
                for k=1:length(app.vars)
                    switch app.vars(k).class
                        case {'single','double','int32','int16','int8'}
                            temp{end+1}=sprintf('%s  [%i,%i]  %s',app.vars(k).name,app.vars(k).size,app.vars(k).class);
                        otherwise
                    end
                end
                app.UITable.ColumnFormat={[],temp,[]};
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name='mat2tec';
            app.FilePathEditField.Value=pwd;
            refresh_data(app);
            app.UITable.Data={};
        end

        % Button pushed function: AddVarButton
        function AddVarButtonPushed(app, event)
            app.UITable.Data=vertcat(app.UITable.Data,{[],[],'x'});
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            select=event.Indices;
            select=select(select(:,2)==3,1);
            app.UITable.Data(select,:)=[];
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices(2)==2
                newData=strsplit(newData);
                newData=newData{1};
                app.UITable.Data{indices(1),1}=newData;
                app.UITable.Data{indices(1),2}=newData;
            elseif indices(2)==1
                if strcmp(newData,'')
                    app.UITable.Data{indices(1),1}=app.UITable.Data{indices(1),2};
                end
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            str=uigetdir;
            if str~=0
                app.FilePathEditField.Value=str;
            end
            figure(app.UIFigure);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            refresh_data(app)
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            import liton_ordered_tec.*
            tec_file = TEC_FILE;
            tec_file.FilePath = app.FilePathEditField.Value;
            tec_file.FileName = app.TitleEditField.Value;
            tec_file.Title = app.TitleEditField.Value;
            if ~isempty(app.UITable.Data)
                tec_file.Variables = app.UITable.Data(:,1)';
            else
                uialert(app.UIFigure,'no var added','Error');
                return;
            end
            tec_file.Zones = TEC_ZONE;
            tec_file.Zones.ZoneName = app.ZoneNameEditField.Value;
            tec_file.Zones.Skip = [app.SkipEditField_1.Value,app.SkipEditField_2.Value,app.SkipEditField_3.Value];
            tec_file.Zones.Begin = [app.BeginEditField_1.Value,app.BeginEditField_2.Value,app.BeginEditField_3.Value];
            tec_file.Zones.EEnd = [app.EndEditField_1.Value,app.EndEditField_2.Value,app.EndEditField_3.Value];
            try
                tec_file.Zones.Data = {};
                for k=1:size(app.UITable.Data,1)
                    tec_file.Zones.Data{end+1} = evalin('base',app.UITable.Data{k,2});
                end
            catch ME
                uialert(app.UIFigure,'the var name wrong','Error');
                return;
            end
            try
                tec_file = tec_file.write_plt();
            catch ME
                uialert(app.UIFigure,ME.message,'Error');
                return;
            end
            uialert(app.UIFigure,'export successfully','Success','Icon','success');
        end

        % Value changed function: TitleEditField
        function TitleEditFieldValueChanged(app, event)
            value = app.TitleEditField.Value;
            if strcmp(value,'')
                app.TitleEditField.Value='untitled';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'name'; 'var'; 'x'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.AddVarButton = uibutton(app.UIFigure, 'push');
            app.AddVarButton.ButtonPushedFcn = createCallbackFcn(app, @AddVarButtonPushed, true);
            app.AddVarButton.Text = 'Add Var';
            app.TitleEditFieldLabel = uilabel(app.UIFigure);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.UIFigure, 'text');
            app.TitleEditField.ValueChangedFcn = createCallbackFcn(app, @TitleEditFieldValueChanged, true);
            app.TitleEditField.Value = 'untitled';
            app.ZoneNameLabel = uilabel(app.UIFigure);
            app.ZoneNameLabel.Text = 'ZoneName';
            app.ZoneNameEditField = uieditfield(app.UIFigure, 'text');
            app.ZoneNameEditField.Value = 'untitled';
            app.BeginEditFieldLabel = uilabel(app.UIFigure);
            app.BeginEditFieldLabel.Text = 'Begin';
            app.BeginEditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.BeginEditField_1.Limits = [0 Inf];
            app.BeginEditField_1.ValueDisplayFormat = '%i';
            app.SkipEditFieldLabel = uilabel(app.UIFigure);
            app.SkipEditFieldLabel.Text = 'Skip';
            app.SkipEditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.SkipEditField_1.Limits = [1 Inf];
            app.SkipEditField_1.ValueDisplayFormat = '%i';
            app.SkipEditField_1.Value = 1;
            app.EndEditFieldLabel = uilabel(app.UIFigure);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.EndEditField_1.Limits = [0 Inf];
            app.EndEditField_1.ValueDisplayFormat = '%i';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = '...';
            app.FilePathEditFieldLabel = uilabel(app.UIFigure);
            app.FilePathEditFieldLabel.Text = 'FilePath';
            app.FilePathEditField = uieditfield(app.UIFigure, 'text');
            app.FilePathEditField.Editable = 'off';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.BeginEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.BeginEditField_2.Limits = [0 Inf];
            app.BeginEditField_2.ValueDisplayFormat = '%i';
            app.BeginEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.BeginEditField_3.Limits = [0 Inf];
            app.BeginEditField_3.ValueDisplayFormat = '%i';
            app.SkipEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.SkipEditField_2.Limits = [1 Inf];
            app.SkipEditField_2.ValueDisplayFormat = '%i';
            app.SkipEditField_2.Value = 1;
            app.SkipEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.SkipEditField_3.Limits = [1 Inf];
            app.SkipEditField_3.ValueDisplayFormat = '%i';
            app.SkipEditField_3.Value = 1;
            app.EndEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EndEditField_2.Limits = [0 Inf];
            app.EndEditField_2.ValueDisplayFormat = '%i';
            app.EndEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EndEditField_3.Limits = [0 Inf];
            app.EndEditField_3.ValueDisplayFormat = '%i';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mat2tec

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end